classdef tugas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        COVID19DataVisualizationUIFigure  matlab.ui.Figure
        DatatoPlotButtonGroup      matlab.ui.container.ButtonGroup
        BothButton                 matlab.ui.control.RadioButton
        CasesButton                matlab.ui.control.RadioButton
        DeathsButton               matlab.ui.control.RadioButton
        AveragedofdaysSliderLabel  matlab.ui.control.Label
        AveragedofdaysSlider       matlab.ui.control.Slider
        CountriesLabel             matlab.ui.control.Label
        Countries                  matlab.ui.control.ListBox
        StateorRegionLabel         matlab.ui.control.Label
        StateorRegion              matlab.ui.control.ListBox
        OptionButtonGroup          matlab.ui.container.ButtonGroup
        CumulativeButton           matlab.ui.control.RadioButton
        DailyButton                matlab.ui.control.RadioButton
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Cases               % Number of cases on a certain country/provinces to Plot
        CasesDaily          % Daily Number of cases on a certain country/provinces
        CasesCumulative     % Cumulative Number of cases on a certain country/provinces to Plot
        Deaths              % Number of death on a certain country/provinces to Plot
        DeathsDaily         % Daily Number of death on a certain country/provinces
        DeathsCumulative    % Cumulative Number of death on a certain country/provinces
        ShowCases           % Graph of Covid Cases
        ShowDeaths          % Graph of Covid Deaths
        ShowBoth            % Graph of Covid Cases and Deaths
        CountryListSelected % Country List
        StateListSelected   % State or Region List
        CasesAverages       % Moving Mean of Covid Cases
        DeathsAverages      % Moving Mean of Covid Deaths
        Country             % All Country Data
        States              % All States Data
        all                 % All Covid_Data
        Index               % Current Index of Country/Region
        Option              % Cumulative/Daily
        AveragingDays       % Moving Mean Slider
    end
    
    methods (Access = private)
        
        function forplot(app,val)           % Separate Cases and Deaths on cell array
            a = cell2mat(val);              % The Output is an array of Cases and Deaths
            [~,length] = size(a);
            len = length/2;
            if mod(len,1) ~= 0
                app.CasesCumulative = zeros(1,double(len + 0.5));
                app.DeathsCumulative = zeros(1,double(len - 0.5));
            else
                app.CasesCumulative = zeros(1,len);
                app.DeathsCumulative= zeros(1,len);       
            end
            i = 0;
            ii = 0;
            for n = 1:length
                if mod(n,2) == 0
                    i = i+1;
                    app.DeathsCumulative(1,i) = a(1,n);
                else
                    ii = ii+1;
                    app.CasesCumulative(1,ii) = a(1,n);
                end
            end
        end
        
        function counter = getdigits(~,val)   % Get the Digits of a Value       
            counter = 0;
            while (val>9)
                b = mod(val,10);
                val = (val-b)/10;
                counter = counter+1;
            end
        end
        
        function daily(app)                   % Get the Daily Cases/Deaths Data
            a = length(app.CasesCumulative);
            for i = 2:a
                if (i == 1)
                    app.CasesDaily(i) = app.CasesCumulative(i);
                    app.DeathsDaily(i) = app.DeathsCumulative(i);
                else
                    app.CasesDaily(i) = app.CasesCumulative(i) - app.CasesCumulative(i-1);
                    app.DeathsDaily(i) = app.DeathsCumulative(i) - app.DeathsCumulative(i-1);
                    if (app.CasesDaily(i)< 0 )
                        app.CasesDaily(i) = 0;
                    end
                    if (app.DeathsDaily(i)< 0 )
                        app.DeathsDaily(i) = 0;
                    end
                end
            end
        end
        
        function [l,mm,in] = getylim(app)      % Get the Limit of Y of Covid Cases and Deaths
            if (app.AveragingDays == 1)
                casedigit = getdigits(app,max(app.Cases));
                deathdigit = getdigits(app,max(app.Deaths));
            else
                casedigit = getdigits(app,max(app.CasesAverages));
                deathdigit = getdigits(app,max(app.DeathsAverages)); 
            end
            in = 9;
            if (max(app.CasesAverages) < 10)   % Set the Range of Y of Cases
                l = 10;
            else  
                if (app.AveragingDays == 1)
                    l = ceil(max(app.Cases)/(10^(casedigit)))* 10^(casedigit);
                else
                    l = ceil(max(app.CasesAverages)/(10^(casedigit)))* 10^(casedigit);
                end
            end       
            
            if (max(app.DeathsAverages) < 10)   % Set the Range of Y of Deaths
                m = 10;
            else
                if (app.AveragingDays == 1)
                    m = ceil(max(app.Deaths)/(10^(deathdigit)))* 10^(deathdigit);
                else
                    m = ceil(max(app.DeathsAverages)/(10^(deathdigit)))* 10^(deathdigit);
                end
            end    
            if (l == 10)
                in = in-4;
            else
                while (mod(l,in) ~=0)
                    in = in-1;
                end
            end
            mm = m;
            while (mod(mm,in) ~= 0)
                mm = mm + (0.1 * m);
            end
        end
        
        function [XAxis,ii] = getX(app)                  % set the Xtick and Xlabel
            ii = ceil(length(app.Cases)/30)+1;
            XAxis = ["01/01","a"];    
            for a = 2:ii
                aa = a;
                if (a > 12)
                    aa = mod(a,12);
                    if (aa == 0)
                        aa = 12;
                    end
                end
                if (aa< 10)
                     e = "0" + string(aa) +"/01";
                else
                     e = string(aa) +"/01";
                end
                XAxis(a) = e;    
            end
        end

        function plotData(app)                          %Function to Plot the Data           
           if (app.Option == 0)
               daily(app)
               app.Cases = app.CasesDaily;
               app.Deaths = app.DeathsDaily;
               optit = "Daily";
           else
               app.Cases = app.CasesCumulative;
               app.Deaths = app.DeathsCumulative;             
               optit = "Cumulative";
           end
           
            app.computeAverages();    
            [CasesLimits,DeathsLimits,Ticks] = getylim(app);
            Leng = (0:CasesLimits/Ticks:CasesLimits);      % Range of Y in Cases
            Leng2 = (0:DeathsLimits/Ticks:DeathsLimits);   % Range of Y in Deaths
            
            if (app.AveragingDays ~= 1)                    % For day mean in title
                a = round(app.AveragingDays,1);
                entit = " (" + string(a)+ "-day mean)";
            else
                entit = " ";
            end
                
            if app.ShowBoth                                % Show Bar dan Plot Graph on the app
                %Title
                if (app.StateListSelected == "All")
                    title(app.UIAxes, optit + " " + 'Number of Cases and Deaths in' + " " + app.CountryListSelected + entit);
                elseif (app.StateListSelected ~= " ")
                    title(app.UIAxes, optit + " " + 'Number of Cases and Deaths in' + " " + app.StateListSelected + entit);
                end
                
                %Cases
                yyaxis(app.UIAxes,"left")
             
                if (max(app.Cases) == 0)                    % In case of no covid Cases
                    bar(app.UIAxes,[])
                    yticks(app.UIAxes, [])
                    
                else    
                    app.UIAxes.YLim = [0 CasesLimits];        
                    if (app.AveragingDays == 1)
                        bar(app.UIAxes,app.Cases) 
                    else
                        bar(app.UIAxes,app.CasesAverages) 
                    end      
                    yticks(app.UIAxes,Leng)
                    yticklabels(app.UIAxes,Leng)
                    app.UIAxes.YAxis(1).Exponent = 0;    
                end
                
                %Deaths
                yyaxis(app.UIAxes,"right")
                ylabel(app.UIAxes,"Deaths")
                if (max(app.Deaths)== 0)                    % In case of no covid Deaths
                    plot(app.UIAxes,0)
                    yticks(app.UIAxes, 0)
                    yticklabels(app.UIAxes, 0)
                else                                  
                    if (app.AveragingDays == 1)
                        plot(app.UIAxes, app.Deaths,'-r') 
                    else
                        plot(app.UIAxes, app.DeathsAverages,'-r') 
                    end
                    app.UIAxes.YLim = [0 DeathsLimits];
                    yticks(app.UIAxes, Leng2)
                    yticklabels(app.UIAxes,Leng2)
                    app.UIAxes.YAxis(2).Exponent = 0;
                end
            end
            
            if app.ShowCases                                % Only Bar Graph on the app
                if (app.StateListSelected == "All")
                    title(app.UIAxes, optit + " " + 'Number of Cases in' + " " + app.CountryListSelected + entit);
                elseif (app.StateListSelected ~= " ")
                    title(app.UIAxes, optit + " " + 'Number of Cases in' + " " + app.StateListSelected + entit);
                end

                yyaxis(app.UIAxes,"right")
                plot(app.UIAxes, 0)
                yticks(app.UIAxes, [])
                
                yyaxis(app.UIAxes, "left")
                if (max(app.Cases) == 0)
                    bar(app.UIAxes,[])
                    yticks(app.UIAxes, [])
                else    
                    app.UIAxes.YLim = [0 CasesLimits]; 
                    if (app.AveragingDays == 1)
                        bar(app.UIAxes,app.Cases) 
                    else
                        bar(app.UIAxes,app.CasesAverages) 
                    end
                    yticks(app.UIAxes,Leng)
                    yticklabels(app.UIAxes,Leng)
                    app.UIAxes.YAxis(1).Exponent = 0;    
                end             
            end
            
            if app.ShowDeaths                               % Only Plot Graph on the app
                if (app.StateListSelected == "All")
                    title(app.UIAxes, optit + " " + 'Number of Deaths in ' + app.CountryListSelected + entit);
                elseif (app.StateListSelected ~= " ")
                    title(app.UIAxes, optit + " " + 'Number of Deaths in ' + app.StateListSelected + entit);
                end
                
                yyaxis(app.UIAxes,"left")
                bar(app.UIAxes,[])
                yticks(app.UIAxes, [])  
                
                yyaxis(app.UIAxes,"right")
                if (max(app.Deaths) == 0)
                    plot(app.UIAxes,0)
                    yticks(app.UIAxes, 0)
                    yticklabels(app.UIAxes,0)
                    
                else  
                    if (app.AveragingDays == 1)
                        plot(app.UIAxes, app.Deaths,'-r') 
                    else
                        plot(app.UIAxes, app.DeathsAverages,'-r') 
                    end
                    app.UIAxes.YLim = [0 DeathsLimits];
                    yticks(app.UIAxes, Leng2)
                    yticklabels(app.UIAxes,Leng2)
                    app.UIAxes.YAxis(2).Exponent = 0;
                end
            end
            hold(app.UIAxes, 'off') 
            [XAxis,Length] = getX(app);                     % set the Xtick and Xlabel 
            xticks(app.UIAxes, (0:length(app.Cases)/(Length-1):length(app.Cases)))
            xticklabels(app.UIAxes, XAxis)
        end
        
        function computeAverages(app)                       % MovMean Function
            app.CasesAverages = movmean(app.Cases,[app.AveragingDays 0]);
            app.DeathsAverages = movmean(app.Deaths,[app.AveragingDays 0]);     
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
            load covid_dataa.mat covid_data;
            app.all = covid_data;
            %Set the ListBoxes
            app.Country = string(covid_data(2:end,1));
            app.Country = unique(app.Country);
            app.Countries.Items = app.Country;  
            app.CountryListSelected = app.Countries.Value;
            app.States = "All";
            app.StateListSelected = "All";
        
            %Set the Slider Value
            app.AveragingDays = app.AveragedofdaysSlider.Value;
           
            %Button Group
            app.ShowCases = app.CasesButton.Value;
            app.ShowDeaths = app.DeathsButton.Value;
            app.ShowBoth = app.BothButton.Value;

 
            % Set the Cumulative Data to Plot
            [index,~] = find(strcmp(app.all, app.CountryListSelected));
            forplot(app,app.all(index(1),3:end))
       
            % Create the bar and plot
           
            app.plotData();
           

        end

        % Selection changed function: DatatoPlotButtonGroup
        function DatatoPlotButtonGroupSelectionChanged(app, event)
            % Set the Data to Plot Value
            app.ShowCases = app.CasesButton.Value;
            app.ShowDeaths = app.DeathsButton.Value ;
            app.ShowBoth = app.BothButton.Value;
            app.plotData();           
        end

        % Value changed function: Countries
        function CountriesValueChanged(app, event)
            % Set the Countries List Box Value
            app.CountryListSelected = app.Countries.Value;
            % Find the Data to Plot in the covid_data
            [app.Index,~] = find(strcmp(app.all, app.CountryListSelected));
            % Set the State or Region List Box
            if (isempty(app.all(2,app.Index)))
                app.States = "All";
            else
                app.States = string(app.all(app.Index:app.Index(end),2));
                app.States(1) = "All";
            end
                app.StateorRegion.Items = app.States;
            forplot(app,app.all(app.Index(1),3:end))
            app.plotData(); 
        end

        % Selection changed function: OptionButtonGroup
        function OptionButtonGroupSelectionChanged(app, event)
            % Set the Option Value
            if (app.DailyButton.Value == 1)
                app.Option = 0;
            elseif (app.CumulativeButton.Value == 1)
                app.Option = 1;
            end
            app.plotData();
        end

        % Value changed function: StateorRegion
        function StateorRegionValueChanged(app, event)
            % Set the State or Region VAlue
            app.StateListSelected = app.StateorRegion.Value;
            % Set Data to Plot if Selected
            if (app.StateListSelected ~= "All")
                [app.Index,~] = find(strcmp(app.all, app.StateListSelected));
            else
                [app.Index,~] = find(strcmp(app.all, app.CountryListSelected));
            end
            forplot(app,app.all(app.Index(1),3:end))
            app.plotData(); 
            
            
        end

        % Value changing function: AveragedofdaysSlider
        function AveragedofdaysSliderValueChanging(app, event)
            % Set the Moving Mean Slider Value
            app.AveragingDays = event.Value;
            app.computeAverages();
            app.plotData();
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.COVID19DataVisualizationUIFigure = uifigure('Visible', 'off');
            app.COVID19DataVisualizationUIFigure.Name = 'COVID-19 Data Visualization';
            app.DatatoPlotButtonGroup = uibuttongroup(app.COVID19DataVisualizationUIFigure);
            app.DatatoPlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatatoPlotButtonGroupSelectionChanged, true);
            app.DatatoPlotButtonGroup.Title = 'Data to Plot';
            app.BothButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.BothButton.Text = 'Both';
            app.BothButton.Value = true;
            app.CasesButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.DeathsButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.DeathsButton.Text = 'Deaths';
            app.AveragedofdaysSliderLabel = uilabel(app.COVID19DataVisualizationUIFigure);
            app.AveragedofdaysSliderLabel.Text = {'Averaged'; '# of days'};
            app.AveragedofdaysSlider = uislider(app.COVID19DataVisualizationUIFigure);
            app.AveragedofdaysSlider.Limits = [1 15];
            app.AveragedofdaysSlider.MajorTicks = [1 3 5 7 9 11 13 15];
            app.AveragedofdaysSlider.ValueChangingFcn = createCallbackFcn(app, @AveragedofdaysSliderValueChanging, true);
            app.AveragedofdaysSlider.Value = 1;
            app.CountriesLabel = uilabel(app.COVID19DataVisualizationUIFigure);
            app.CountriesLabel.Text = 'Countries :';
            app.Countries = uilistbox(app.COVID19DataVisualizationUIFigure);
            app.Countries.Items = {'Afghanistan', 'Albania', 'Algeria', 'Andorra', 'Angola', 'Antigua and Barbados', 'Argentina', 'Item 1', 'Item 2', 'Item 3', 'Item 4', 'Item 5', 'Item 6', 'Item 7', 'Item 8', 'Item 9', 'Item 10', 'Item 11', 'Item 12', 'Item 13', 'Item 14', 'Item 15', 'Item 16', 'Item 17', 'Item 18', 'Item 19', 'Item 20', 'Item 21', 'Item 22', 'Item 23', 'Item 24', 'Item 25', 'Item 26', 'Item 27', 'Item 28', 'Item 29', 'Item 30'};
            app.Countries.ValueChangedFcn = createCallbackFcn(app, @CountriesValueChanged, true);
            app.Countries.Value = 'Afghanistan';
            app.StateorRegionLabel = uilabel(app.COVID19DataVisualizationUIFigure);
            app.StateorRegionLabel.Text = {'State or '; 'Region :'};
            app.StateorRegion = uilistbox(app.COVID19DataVisualizationUIFigure);
            app.StateorRegion.Items = {'All'};
            app.StateorRegion.ValueChangedFcn = createCallbackFcn(app, @StateorRegionValueChanged, true);
            app.StateorRegion.Value = 'All';
            app.OptionButtonGroup = uibuttongroup(app.COVID19DataVisualizationUIFigure);
            app.OptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.OptionButtonGroup.Title = 'Option';
            app.CumulativeButton = uiradiobutton(app.OptionButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionButtonGroup);
            app.DailyButton.Text = 'Daily';
            app.UIAxes = uiaxes(app.COVID19DataVisualizationUIFigure);
            title(app.UIAxes, 'Daily Number of Cases and Deaths of Covid in The Earth')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, 'Cases')
            % Show the figure after all components are created
            app.COVID19DataVisualizationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tugas

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.COVID19DataVisualizationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.COVID19DataVisualizationUIFigure)
        end
    end
end